classdef Chapter_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        Ex12Tab                        matlab.ui.container.Tab
        GridLayout                     matlab.ui.container.GridLayout
        Iangle                         matlab.ui.control.EditField
        Iw                             matlab.ui.control.EditField
        Imag                           matlab.ui.control.EditField
        Vangle                         matlab.ui.control.EditField
        Vw                             matlab.ui.control.EditField
        Vmag                           matlab.ui.control.EditField
        avgP_2                         matlab.ui.control.EditField
        TheaveragepowerEditFieldLabel_2  matlab.ui.control.Label
        DONTDELETELabel                matlab.ui.control.Label
        Impedance                      matlab.ui.control.EditField
        ImpedanceZEditFieldLabel       matlab.ui.control.Label
        Current                        matlab.ui.control.EditField
        CurrentIEditFieldLabel         matlab.ui.control.Label
        Voltage                        matlab.ui.control.EditField
        VoltageVEditFieldLabel         matlab.ui.control.Label
        ex2_clearButton                matlab.ui.control.Button
        ex2_calculateButton            matlab.ui.control.Button
        example1_2                     matlab.ui.control.Label
        avgP                           matlab.ui.control.EditField
        TheaveragepowerEditFieldLabel  matlab.ui.control.Label
        insP                           matlab.ui.control.EditField
        TheinstantaneouspowerEditFieldLabel  matlab.ui.control.Label
        itLabel                        matlab.ui.control.Label
        vtLabel                        matlab.ui.control.Label
        ex1_clearButton                matlab.ui.control.Button
        ex1_calculateButton            matlab.ui.control.Button
        Label                          matlab.ui.control.Label
        wLabel                         matlab.ui.control.Label
        MagLabel                       matlab.ui.control.Label
        example1                       matlab.ui.control.Label
        Ex3Tab                         matlab.ui.container.Tab
        avgPcap                        matlab.ui.control.EditField
        TheaveragepowerabsorbedbythecapacitorEditFieldLabel  matlab.ui.control.Label
        avgPres                        matlab.ui.control.EditField
        TheaveragepowerabsorbedbytheresistorEditFieldLabel  matlab.ui.control.Label
        avgPvolt                       matlab.ui.control.EditField
        TheaveragepowersupliedbythevoltagesourceEditFieldLabel  matlab.ui.control.Label
        ex3_clearButton                matlab.ui.control.Button
        ex3_calculateButton            matlab.ui.control.Button
        Label_2                        matlab.ui.control.Label
        cap                            matlab.ui.control.EditField
        res                            matlab.ui.control.EditField
        volt                           matlab.ui.control.EditField
        Image                          matlab.ui.control.Image
        Ex4Tab                         matlab.ui.container.Tab
        ex4_clearButton                matlab.ui.control.Button
        ex4_calculateButton            matlab.ui.control.Button
        avgPtot                        matlab.ui.control.EditField
        ThetotalpowerLabel             matlab.ui.control.Label
        avgPca                         matlab.ui.control.EditField
        TheaveragepowerabsorbedbythecapacitorEditFieldLabel_2  matlab.ui.control.Label
        avgPvo                         matlab.ui.control.EditField
        TheaveragepowerabsorbedbythevoltagesourceLabel  matlab.ui.control.Label
        avgPin                         matlab.ui.control.EditField
        TheaveragepowerabsorbedbytheinductorLabel  matlab.ui.control.Label
        avgPre                         matlab.ui.control.EditField
        TheaveragepowerabsorbedbytheresistorEditFieldLabel_2  matlab.ui.control.Label
        avgPcu                         matlab.ui.control.EditField
        TheaveragepowersupliedbythecurrentsourceLabel  matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        volt4                          matlab.ui.control.EditField
        cap4                           matlab.ui.control.EditField
        ind4                           matlab.ui.control.EditField
        res4                           matlab.ui.control.EditField
        cur4                           matlab.ui.control.EditField
        Image2                         matlab.ui.control.Image
        Ex5Tab                         matlab.ui.container.Tab
        maxPower                       matlab.ui.control.EditField
        TheMaximumAveragePowerLabel    matlab.ui.control.Label
        LoadImp                        matlab.ui.control.EditField
        TheLoadImpedanceLabel          matlab.ui.control.Label
        ex5_clearButton                matlab.ui.control.Button
        ex5_calculateButton            matlab.ui.control.Button
        ind5                           matlab.ui.control.EditField
        cap5                           matlab.ui.control.EditField
        res5_2                         matlab.ui.control.EditField
        res5                           matlab.ui.control.EditField
        volt5                          matlab.ui.control.EditField
        Label_4                        matlab.ui.control.Label
        Image3                         matlab.ui.control.Image
        Ex6Tab                         matlab.ui.container.Tab
        maxPowRload                    matlab.ui.control.EditField
        TheMaximumAveragePowerLabel_2  matlab.ui.control.Label
        LoadRes                        matlab.ui.control.EditField
        TheLoadImpedanceLabel_2        matlab.ui.control.Label
        ex6_clearButton                matlab.ui.control.Button
        ex6_calculateButton            matlab.ui.control.Button
        Label_5                        matlab.ui.control.Label
        ind6                           matlab.ui.control.EditField
        cap6                           matlab.ui.control.EditField
        res6                           matlab.ui.control.EditField
        volt6                          matlab.ui.control.EditField
        Image4                         matlab.ui.control.Image
        Ex7Tab                         matlab.ui.container.Tab
        resPower                       matlab.ui.control.EditField
        TheMaximumAveragePowerLabel_3  matlab.ui.control.Label
        Irms                           matlab.ui.control.EditField
        TheLoadImpedanceLabel_3        matlab.ui.control.Label
        valueRes                       matlab.ui.control.EditField
        ValueofResistorEditFieldLabel  matlab.ui.control.Label
        ex7_calculateButton            matlab.ui.control.Button
        Label_6                        matlab.ui.control.Label
        Image5                         matlab.ui.control.Image
        Ex8Tab                         matlab.ui.container.Tab
        resPower_2                     matlab.ui.control.EditField
        TheMaximumAveragePowerLabel_4  matlab.ui.control.Label
        Vrms                           matlab.ui.control.EditField
        TheLoadImpedanceLabel_4        matlab.ui.control.Label
        valueRes2                      matlab.ui.control.EditField
        ValueofResistorEditField_2Label  matlab.ui.control.Label
        ex8_calculateButton            matlab.ui.control.Button
        Image6                         matlab.ui.control.Image
        Label_7                        matlab.ui.control.Label
    end

        methods (Access = private)
        % function that converts polar to rectangular form
        function rectForm = polarToRect(app, event)
            value = event.Source.Value;
            splitOperator = split(value, ',');
            if length(splitOperator) == 2
                app.DONTDELETELabel.Text = value;
                a = app.DONTDELETELabel.Text;
                a = split(a, ',');
                mag = eval(a{1,1});
                phaseAngle = eval(a{2,1});
                real = mag * cos(deg2rad(phaseAngle));
                real = round(real, 2);
                imag = mag * sin(deg2rad(phaseAngle));
                imag = round(imag, 2);
                rectForm = string(complex(real, imag));
            else
                rectForm = value;
            end
        end
        end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ex1_calculateButton
        function ex1_calculateButtonPushed(app, event)
            Vmagn = eval(app.Vmag.Value); % magnitude of vt
            Vaf = eval(app.Vw.Value); % angular frequency of vt
            Vang = eval(app.Vangle.Value); % phase angle of vt
            Imagn = eval(app.Imag.Value);  % magnitude of it
            Iaf = eval(app.Iw.Value); % angular frequency of it
            Iang = eval(app.Iangle.Value); % phase angle of it
 
            totalAngle = Vang + Iang; % total phase angle
            phaseAngle = Vang - Iang; % phase angle between v and i
            totalw = Vaf + Iaf;
            
            % calculations
            insPow = (Vmagn * Imagn) * cos(deg2rad(phaseAngle)) * 0.5 / 1000; % inst power
            insPow = round(insPow, 3);
            e1_avgP = 0.5 * Vmagn * Imagn * cos(deg2rad(phaseAngle)) / 1000; % average power
            e1_avgP = round(e1_avgP, 3);
            
            % print operations
            app.insP.Value = string(insPow) ...
            + ' + ' + string(Vmagn*Imagn*0.5 / 1000) ...
            + '*cos(' + string(totalw) + 't+' + string(totalAngle)+ ') kW';
            app.avgP.Value = string(e1_avgP) + ' kW';
        end

        % Button pushed function: ex1_clearButton
        function ex1_clearButtonPushed(app, event)
            app.Vmag.Value = '';
            app.Vw.Value = '';
            app.Vangle.Value = '';
            app.Imag.Value = '';
            app.Iw.Value = '';
            app.Iangle.Value = '';
            app.insP.Value = '';
            app.avgP.Value = '';
        end

        % Callback function: Current, Impedance, Voltage, Voltage, cap, cap, 
        % cap4, cap5, cap6, cur4, ind4, ind5, ind6, res, res, res4, res5, 
        % res5_2, res6, valueRes, valueRes, valueRes2, volt, volt, volt4, 
        % volt5, volt6
        function polarForm(app, event)
            event.Source.Value = polarToRect(app, event);
        end

        % Button pushed function: ex2_calculateButton
        function ex2_calculateButtonPushed(app, event)
            % condition of voltage is none
            if app.Voltage.Value == '-'
                I = eval(app.Current.Value);
                Z = eval(app.Impedance.Value);
                V = I * Z; % the voltage

            % condition of current is none
            elseif app.Current.Value == '-'
                V = eval(app.Voltage.Value);
                Z = eval(app.Impedance.Value);
                I = V / Z; % the current

             else
                V = eval(app.Voltage.Value);
                I = eval(app.Current.Value);
            end
            
            % calculations
            V_phase = rad2deg(angle(V));
            I_phase = rad2deg(angle(I));
            avgPower = 0.5 * abs(V) * abs(I) * cos(deg2rad(V_phase - I_phase));
            
            % print operation
            app.avgP_2.Value = string(avgPower) + ' Watt';
        end

        % Button pushed function: ex2_clearButton
        function ex2_clearButtonPushed(app, event)
            app.Voltage.Value = '';
            app.Current.Value = '';
            app.Impedance.Value = '';
            app.avgP_2.Value = '';
        end

        % Button pushed function: ex3_calculateButton
        function ex3_calculateButtonPushed(app, event)
            % input values
            voltage = eval(app.volt.Value);
            resitance = eval(app.res.Value);
            capacitor = eval(app.cap.Value);
            impedance = resitance + capacitor;

            % calculations
            current = voltage / impedance;
            Vres = resitance * current;
            Vcap = capacitor * current;

            % phase angles
            Vphase_v = rad2deg(angle(voltage));
            Vphase_r = rad2deg(angle(Vres));
            Vphase_c = rad2deg(angle(Vcap));
            Iphase = rad2deg(angle(current));

            % average powers
            avPvolt = 0.5 * abs(voltage) * abs(current) * cos(deg2rad(Vphase_v - Iphase));
            avPres = 0.5 * abs(Vres) * abs(current) * cos(deg2rad(Vphase_r - Iphase));
            avPcap = 0.5 * abs(Vcap) * abs(current) * cos(deg2rad(Vphase_c - Iphase));
            avPcap = round(avPcap, 2);

            % print operations
            app.avgPvolt.Value = string(avPvolt) + ' Watt';
            app.avgPres.Value = string(avPres) + ' Watt';
            app.avgPcap.Value = string(avPcap) + ' Watt';
        end

        % Button pushed function: ex3_clearButton
        function ex3_clearButtonPushed(app, event)
            app.volt.Value = '';
            app.res.Value = '';
            app.cap.Value = '';
            app.avgPvolt.Value = '';
            app.avgPres.Value = '';
            app.avgPcap.Value = '';
        end

        % Button pushed function: ex4_calculateButton
        function ex4_calculateButtonPushed(app, event)
            % input values
            current = eval(app.cur4.Value);
            resistor = eval(app.res4.Value);
            inductor = eval(app.ind4.Value);
            capacitor = eval(app.cap4.Value);
            voltage = eval(app.volt4.Value);

            % currents from mesh analysis
            I1 = current;
            I2 = ((inductor * I1) - voltage) / (inductor + capacitor);
            Iind = I1 - I2;
            V_I1 = (resistor * I1) + (inductor * (I1 - I2)); % voltage of I1
            Vres = resistor * I1; % voltage of resistor
            Vind = (I1 - I2) * inductor; % voltage of inductor
            Vcap = capacitor * I2; % voltage of capacitor

            % phase angles
            Vphase_I1 = rad2deg(angle(V_I1));
            Vphase_r = rad2deg(angle(Vres));
            Vphase_i = rad2deg(angle(Vind));
            Vphase_v = rad2deg(angle(voltage));
            Vphase_c = rad2deg(angle(Vcap));
            I1phase = rad2deg(angle(I1));
            I2phase = rad2deg(angle(I2));
            Iindphase = rad2deg(angle(Iind));
            
            % power values
            Pcur = -(0.5 * abs(V_I1) * abs(I1)) * cos(deg2rad(Vphase_I1 - I1phase)); % P1
            Pres = 0.5 * abs(Vres) * abs(I1) * cos(deg2rad(Vphase_r - I1phase)); % P2
            Pind = 0.5 * abs(Vind) * abs(Iind) * cos(deg2rad(Vphase_i - Iindphase)); % P3
            Pind = round(Pind, 3);
            Pcap = 0.5 * abs(Vcap) * abs(I2) * cos(deg2rad(Vphase_c - I2phase)); % P4
            Pcap = round(Pcap, 3);
            Pvolt = 0.5 * abs(voltage) * abs(I2) * cos(deg2rad(Vphase_v - I2phase)); % P5
            Ptotal = Pcur + Pres + Pind + Pcap + Pvolt; % total power
            Ptotal = round(Ptotal, 3);
 
            % print operations
            app.avgPcu.Value = string(Pcur) + ' Watt';
            app.avgPre.Value = string(Pres) + ' Watt';
            app.avgPin.Value = string(Pind) + ' Watt';
            app.avgPca.Value = string(Pcap) + ' Watt';
            app.avgPvo.Value = string(Pvolt) + ' Watt';
            app.avgPtot.Value = string(Ptotal) + ' Watt';
        end

        % Button pushed function: ex4_clearButton
        function ex4_clearButtonPushed(app, event)
            app.cur4.Value = '';
            app.res4.Value = '';
            app.ind4.Value = '';
            app.cap4.Value = '';
            app.volt4.Value = '';
            app.avgPcu.Value = '';
            app.avgPre.Value = '';
            app.avgPin.Value = '';
            app.avgPca.Value = '';
            app.avgPvo.Value = '';
            app.avgPtot.Value = '';
        end

        % Button pushed function: ex5_calculateButton
        function ex5_calculateButtonPushed(app, event)
            % input values
            voltage = eval(app.volt5.Value);
            res1 = eval(app.res5.Value);
            res2 = eval(app.res5_2.Value);
            capacitor = eval(app.cap5.Value);
            inductor = eval(app.ind5.Value);
            
            % calculations
            Zth = inductor + (res1 * (res2 + capacitor)) / (res1 + res2 + capacitor); % Z Thevenin
            Vth = ((res2 + capacitor) / (res1 + res2 + capacitor)) * abs(voltage); % V Thevenin
            
            ZLoad = conj(Zth); % load impedance
            Rth = real(ZLoad); % R Thevenin
            Pmax = (abs(Vth)^2) / (8 * Rth); % maximum average power
            
            % print operations
            app.LoadImp.Value = string(ZLoad) + ' Ω';
            app.maxPower.Value = string(Pmax) + ' Watt';
        end

        % Button pushed function: ex5_clearButton
        function ex5_clearButtonPushed(app, event)
            app.volt5.Value = '';
            app.res5.Value = '';
            app.res5.Value = '';
            app.cap5.Value = '';
            app.ind5.Value = '';
            app.LoadImp.Value = '';
            app.maxPower.Value = '';
        end

        % Button pushed function: ex6_calculateButton
        function ex6_calculateButtonPushed(app, event)
            % input values
            voltage = eval(app.volt6.Value);
            resistor = eval(app.res6.Value);
            capacitor = eval(string(app.cap6.Value));
            inductor = eval(string(app.ind6.Value));
            
            % calculations
            Zth = ((resistor + capacitor) * (inductor)) / (resistor + capacitor + inductor); % Z Thevenin
            Vth = (inductor / (resistor + capacitor + inductor)) * voltage; % V Thevenin

            RLoad = abs(Zth); % the purely resistive load
            Iload = Vth / (Zth + RLoad); % the current of the load
            Pmax = 0.5 * abs(Iload)^2 * RLoad; % max power of Rload
            
            % print operations
            app.LoadRes.Value = string(RLoad) + ' Ω';
            app.maxPowRload.Value = string(Pmax) + ' Watt';
        end

        % Button pushed function: ex6_clearButton
        function ex6_clearButtonPushed(app, event)
            app.volt6.Value = '';
            app.res6.Value = '';
            app.cap6.Value = '';
            app.ind6.Value = '';
            app.LoadRes.Value = '';
            app.maxPowRload.Value = '';
        end

        % Button pushed function: ex7_calculateButton
        function ex7_calculateButtonPushed(app, event)
            % values on the figure
            period = 4;
            lim1 = 0:0.1:2;
            lim2 = 2:0.1:4;

            resistor = eval(app.valueRes.Value); % resistor

            func1 = 5^2 * lim1.^2; % function 1
            func2 = (-10)^2 * lim2.^0; % function 2

            % integral operations
            avg1 = (func1(1:length(lim1)-1) + diff(func1) / 2);
            avg2 = (func2(1:length(lim2)-1) + diff(func2) / 2);
            integral1 = sum(diff(lim1).*avg1);
            integral2 = sum(diff(lim2).*avg2);
            
            % calculations
            valueIrms = sqrt((integral1 + integral2) / period); % current rms value
            valuePres = valueIrms^2 * resistor;
            
            % print operations
            app.Irms.Value = string(valueIrms) + ' A';
            app.resPower.Value = string(valuePres) + ' Watt';
        end

        % Button pushed function: ex8_calculateButton
        function ex8_calculateButtonPushed(app, event)
            % values on the figure
            period = 2 * pi;
            lim1 = 0:0.1:pi;
            lim2 = pi:0.1:(2*pi);

            resistor = eval(app.valueRes2.Value); % resistor

            func1 = 10^2 * sin(lim1).^2; % function 1
            func2 = 0 * lim2.^0; % function 2
            
            % integral operations
            avg1 = (func1(1:length(lim1)-1) + diff(func1) / 2);
            avg2 = (func2(1:length(lim2)-1) + diff(func2) / 2);
            integral1 = sum(diff(lim1).*avg1);
            integral2 = sum(diff(lim2).*avg2);
            
            % calculations
            valueVrms = sqrt((integral1 + integral2) / period); % voltage rms value
            valuePres = valueVrms^2 / resistor; 
            
            % print operations
            app.Vrms.Value = string(valueVrms) + ' V';
            app.resPower_2.Value = string(valuePres) + ' Watt';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Ex12Tab = uitab(app.TabGroup);
            app.Ex12Tab.Title = 'Ex-1-2';
            app.GridLayout = uigridlayout(app.Ex12Tab);
            app.example1 = uilabel(app.GridLayout);
            app.example1.Text = {'Example 1: Calculation the instantaneous power and the average power.'; '                        * Sample value; v(t) or i(t) = 10cos(122t-60)  ---> mag:10, w:122, θ:-60'};
            app.MagLabel = uilabel(app.GridLayout);
            app.MagLabel.Text = 'Mag';
            app.wLabel = uilabel(app.GridLayout);
            app.wLabel.Text = 'w';
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = 'θ';
            app.ex1_calculateButton = uibutton(app.GridLayout, 'push');
            app.ex1_calculateButton.ButtonPushedFcn = createCallbackFcn(app, @ex1_calculateButtonPushed, true);
            app.ex1_calculateButton.Text = 'CALCULATE';
            app.ex1_clearButton = uibutton(app.GridLayout, 'push');
            app.ex1_clearButton.ButtonPushedFcn = createCallbackFcn(app, @ex1_clearButtonPushed, true);
            app.ex1_clearButton.Text = 'CLEAR';
            app.vtLabel = uilabel(app.GridLayout);
            app.vtLabel.Text = 'v(t)';
            app.itLabel = uilabel(app.GridLayout);
            app.itLabel.Text = 'i(t)';
            app.TheinstantaneouspowerEditFieldLabel = uilabel(app.GridLayout);
            app.TheinstantaneouspowerEditFieldLabel.Text = 'The instantaneous power;';
            app.insP = uieditfield(app.GridLayout, 'text');
            app.insP.Editable = 'off';
            app.insP.Placeholder = 'P';
            app.TheaveragepowerEditFieldLabel = uilabel(app.GridLayout);
            app.TheaveragepowerEditFieldLabel.Text = 'The average power:';
            app.avgP = uieditfield(app.GridLayout, 'text');
            app.avgP.Editable = 'off';
            app.avgP.Placeholder = 'P';
            app.example1_2 = uilabel(app.GridLayout);
            app.example1_2.Text = {'Example 2: Calculation the average power absorbed by an impedance. '; '* Sample value;  3+4i, -5i, 10'; '                                  if value is polar form, (mag, angle) ---> 20, 30'; '                                  if V or I is none ---> -'};
            app.ex2_calculateButton = uibutton(app.GridLayout, 'push');
            app.ex2_calculateButton.ButtonPushedFcn = createCallbackFcn(app, @ex2_calculateButtonPushed, true);
            app.ex2_calculateButton.Text = 'CALCULATE';
            app.ex2_clearButton = uibutton(app.GridLayout, 'push');
            app.ex2_clearButton.ButtonPushedFcn = createCallbackFcn(app, @ex2_clearButtonPushed, true);
            app.ex2_clearButton.Text = 'CLEAR';
            app.VoltageVEditFieldLabel = uilabel(app.GridLayout);
            app.VoltageVEditFieldLabel.Text = 'Voltage (V)';
            app.Voltage = uieditfield(app.GridLayout, 'text');
            app.Voltage.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.Voltage.ValueChangingFcn = createCallbackFcn(app, @polarForm, true);
            app.CurrentIEditFieldLabel = uilabel(app.GridLayout);
            app.CurrentIEditFieldLabel.Text = 'Current (I)';
            app.Current = uieditfield(app.GridLayout, 'text');
            app.Current.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.ImpedanceZEditFieldLabel = uilabel(app.GridLayout);
            app.ImpedanceZEditFieldLabel.Text = 'Impedance(Z)';
            app.Impedance = uieditfield(app.GridLayout, 'text');
            app.Impedance.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.DONTDELETELabel = uilabel(app.GridLayout);
            app.DONTDELETELabel.Visible = 'off';
            app.DONTDELETELabel.Text = 'DONT DELETE!';
            app.TheaveragepowerEditFieldLabel_2 = uilabel(app.GridLayout);
            app.TheaveragepowerEditFieldLabel_2.Text = 'The average power:';
            app.avgP_2 = uieditfield(app.GridLayout, 'text');
            app.avgP_2.Editable = 'off';
            app.avgP_2.Placeholder = 'P';
            app.Vmag = uieditfield(app.GridLayout, 'text');
            app.Vw = uieditfield(app.GridLayout, 'text');
            app.Vangle = uieditfield(app.GridLayout, 'text');
            app.Imag = uieditfield(app.GridLayout, 'text');
            app.Iw = uieditfield(app.GridLayout, 'text');
            app.Iangle = uieditfield(app.GridLayout, 'text');
            app.Ex3Tab = uitab(app.TabGroup);
            app.Ex3Tab.Title = 'Ex-3';
            app.Image = uiimage(app.Ex3Tab);
            app.Image.ImageSource = 'ch1-ex3.png';
            app.volt = uieditfield(app.Ex3Tab, 'text');
            app.volt.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.volt.ValueChangingFcn = createCallbackFcn(app, @polarForm, true);
            app.volt.Placeholder = 'V';
            app.res = uieditfield(app.Ex3Tab, 'text');
            app.res.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.res.ValueChangingFcn = createCallbackFcn(app, @polarForm, true);
            app.res.Placeholder = 'Ω';
            app.cap = uieditfield(app.Ex3Tab, 'text');
            app.cap.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.cap.ValueChangingFcn = createCallbackFcn(app, @polarForm, true);
            app.cap.Placeholder = 'Ω';
            app.Label_2 = uilabel(app.Ex3Tab);
            app.Label_2.Text = {'Example 3: For the circuit shown in the figure;'; 'Calculation,'; '1. The average power supplied by'; 'the source and'; '2. The average power absorbed by'; 'the resistor.'; '* Sample values; '; 'polar form (mag,angle) --> 10,90'; 'rectangular form --> 2-5i, 3i, 22'};
            app.ex3_calculateButton = uibutton(app.Ex3Tab, 'push');
            app.ex3_calculateButton.ButtonPushedFcn = createCallbackFcn(app, @ex3_calculateButtonPushed, true);
            app.ex3_calculateButton.Text = 'CALCULATE';
            app.ex3_clearButton = uibutton(app.Ex3Tab, 'push');
            app.ex3_clearButton.ButtonPushedFcn = createCallbackFcn(app, @ex3_clearButtonPushed, true);
            app.ex3_clearButton.Text = 'CLEAR';
            app.TheaveragepowersupliedbythevoltagesourceEditFieldLabel = uilabel(app.Ex3Tab);
            app.TheaveragepowersupliedbythevoltagesourceEditFieldLabel.Text = 'The average power suplied by the voltage source:';
            app.avgPvolt = uieditfield(app.Ex3Tab, 'text');
            app.avgPvolt.Editable = 'off';
            app.avgPvolt.Placeholder = 'P';
            app.TheaveragepowerabsorbedbytheresistorEditFieldLabel = uilabel(app.Ex3Tab);
            app.TheaveragepowerabsorbedbytheresistorEditFieldLabel.Text = 'The average power absorbed by the resistor:';
            app.avgPres = uieditfield(app.Ex3Tab, 'text');
            app.avgPres.Editable = 'off';
            app.avgPres.Placeholder = 'P';
            app.TheaveragepowerabsorbedbythecapacitorEditFieldLabel = uilabel(app.Ex3Tab);
            app.TheaveragepowerabsorbedbythecapacitorEditFieldLabel.Text = 'The average power absorbed by the capacitor:';
            app.avgPcap = uieditfield(app.Ex3Tab, 'text');
            app.avgPcap.Editable = 'off';
            app.avgPcap.Placeholder = 'P';
            app.Ex4Tab = uitab(app.TabGroup);
            app.Ex4Tab.Title = 'Ex-4';
            app.Image2 = uiimage(app.Ex4Tab);
            app.Image2.ImageSource = 'ch1-ex4.png';
            app.cur4 = uieditfield(app.Ex4Tab, 'text');
            app.cur4.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.cur4.Placeholder = 'A';
            app.res4 = uieditfield(app.Ex4Tab, 'text');
            app.res4.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.res4.Placeholder = 'Ω';
            app.ind4 = uieditfield(app.Ex4Tab, 'text');
            app.ind4.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.ind4.Placeholder = 'Ω';
            app.cap4 = uieditfield(app.Ex4Tab, 'text');
            app.cap4.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.cap4.Placeholder = 'Ω';
            app.volt4 = uieditfield(app.Ex4Tab, 'text');
            app.volt4.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.volt4.Placeholder = 'V';
            app.Label_3 = uilabel(app.Ex4Tab);
            app.Label_3.Text = {'Example 4: For the circuit shown in the figure;'; 'Calculation,'; '1. The average power absorbed by the'; 'resistor and inductor and'; '2. The average power supplied'; 'by the voltage source.'; '* Sample values;'; 'polar form (mag,angle) --> 10,90'; 'rectangular form --> 2-5i, 3i, 22'};
            app.TheaveragepowersupliedbythecurrentsourceLabel = uilabel(app.Ex4Tab);
            app.TheaveragepowersupliedbythecurrentsourceLabel.Text = 'The average power suplied by the current source:';
            app.avgPcu = uieditfield(app.Ex4Tab, 'text');
            app.avgPcu.Editable = 'off';
            app.avgPcu.Placeholder = 'P1';
            app.TheaveragepowerabsorbedbytheresistorEditFieldLabel_2 = uilabel(app.Ex4Tab);
            app.TheaveragepowerabsorbedbytheresistorEditFieldLabel_2.Text = 'The average power absorbed by the resistor:';
            app.avgPre = uieditfield(app.Ex4Tab, 'text');
            app.avgPre.Editable = 'off';
            app.avgPre.Placeholder = 'P2';
            app.TheaveragepowerabsorbedbytheinductorLabel = uilabel(app.Ex4Tab);
            app.TheaveragepowerabsorbedbytheinductorLabel.Text = 'The average power absorbed by the inductor:';
            app.avgPin = uieditfield(app.Ex4Tab, 'text');
            app.avgPin.Editable = 'off';
            app.avgPin.Placeholder = 'P3';
            app.TheaveragepowerabsorbedbythevoltagesourceLabel = uilabel(app.Ex4Tab);
            app.TheaveragepowerabsorbedbythevoltagesourceLabel.Text = 'The average power absorbed by the voltage source:';
            app.avgPvo = uieditfield(app.Ex4Tab, 'text');
            app.avgPvo.Editable = 'off';
            app.avgPvo.Placeholder = 'P5';
            app.TheaveragepowerabsorbedbythecapacitorEditFieldLabel_2 = uilabel(app.Ex4Tab);
            app.TheaveragepowerabsorbedbythecapacitorEditFieldLabel_2.Text = 'The average power absorbed by the capacitor:';
            app.avgPca = uieditfield(app.Ex4Tab, 'text');
            app.avgPca.Editable = 'off';
            app.avgPca.Placeholder = 'P4';
            app.ThetotalpowerLabel = uilabel(app.Ex4Tab);
            app.ThetotalpowerLabel.Text = 'The total power:';
            app.avgPtot = uieditfield(app.Ex4Tab, 'text');
            app.avgPtot.Editable = 'off';
            app.avgPtot.Placeholder = 'Ptot';
            app.ex4_calculateButton = uibutton(app.Ex4Tab, 'push');
            app.ex4_calculateButton.ButtonPushedFcn = createCallbackFcn(app, @ex4_calculateButtonPushed, true);
            app.ex4_calculateButton.Text = 'CALCULATE';
            app.ex4_clearButton = uibutton(app.Ex4Tab, 'push');
            app.ex4_clearButton.ButtonPushedFcn = createCallbackFcn(app, @ex4_clearButtonPushed, true);
            app.ex4_clearButton.Text = 'CLEAR';
            app.Ex5Tab = uitab(app.TabGroup);
            app.Ex5Tab.Title = 'Ex-5';
            app.Image3 = uiimage(app.Ex5Tab);
            app.Image3.ImageSource = 'ch1-ex5.png';
            app.Label_4 = uilabel(app.Ex5Tab);
            app.Label_4.Text = {'Example 5: For the circuit shown in the figure;'; 'Calculation,'; '1. The load impedance (ZL) and'; '2. The maximum average power.'; '* Sample values; '; 'polar form (mag,angle) --> 30,10'; 'rectangular form --> 3-i, -5i, 14'};
            app.volt5 = uieditfield(app.Ex5Tab, 'text');
            app.volt5.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.volt5.Placeholder = 'V';
            app.res5 = uieditfield(app.Ex5Tab, 'text');
            app.res5.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.res5.Placeholder = 'Ω';
            app.res5_2 = uieditfield(app.Ex5Tab, 'text');
            app.res5_2.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.res5_2.Placeholder = 'Ω';
            app.cap5 = uieditfield(app.Ex5Tab, 'text');
            app.cap5.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.cap5.Placeholder = 'Ω';
            app.ind5 = uieditfield(app.Ex5Tab, 'text');
            app.ind5.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.ind5.Placeholder = 'Ω';
            app.ex5_calculateButton = uibutton(app.Ex5Tab, 'push');
            app.ex5_calculateButton.ButtonPushedFcn = createCallbackFcn(app, @ex5_calculateButtonPushed, true);
            app.ex5_calculateButton.Text = 'CALCULATE';
            app.ex5_clearButton = uibutton(app.Ex5Tab, 'push');
            app.ex5_clearButton.ButtonPushedFcn = createCallbackFcn(app, @ex5_clearButtonPushed, true);
            app.ex5_clearButton.Text = 'CLEAR';
            app.TheLoadImpedanceLabel = uilabel(app.Ex5Tab);
            app.TheLoadImpedanceLabel.Text = 'The load impedance:';
            app.LoadImp = uieditfield(app.Ex5Tab, 'text');
            app.LoadImp.Editable = 'off';
            app.LoadImp.Placeholder = 'ZLoad';
            app.TheMaximumAveragePowerLabel = uilabel(app.Ex5Tab);
            app.TheMaximumAveragePowerLabel.Text = 'The maximum average power:';
            app.maxPower = uieditfield(app.Ex5Tab, 'text');
            app.maxPower.Editable = 'off';
            app.maxPower.Placeholder = 'Pmax';
            app.Ex6Tab = uitab(app.TabGroup);
            app.Ex6Tab.Title = 'Ex-6';
            app.Image4 = uiimage(app.Ex6Tab);
            app.Image4.ImageSource = 'ch1-ex6.png';
            app.volt6 = uieditfield(app.Ex6Tab, 'text');
            app.volt6.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.volt6.Placeholder = 'V';
            app.res6 = uieditfield(app.Ex6Tab, 'text');
            app.res6.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.res6.Placeholder = 'Ω';
            app.cap6 = uieditfield(app.Ex6Tab, 'text');
            app.cap6.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.cap6.Placeholder = 'Ω';
            app.ind6 = uieditfield(app.Ex6Tab, 'text');
            app.ind6.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.ind6.Placeholder = 'Ω';
            app.Label_5 = uilabel(app.Ex6Tab);
            app.Label_5.Text = {'Example 6: For the circuit shown in the figure;'; 'Calculation,'; '1. The resistive load (RL) and'; '2. The maximum average power absorbed by RL.'; '* Sample values; '; 'polar form (mag,angle) --> 40,50'; 'rectangular form --> 3-i, -5i, 14'};
            app.ex6_calculateButton = uibutton(app.Ex6Tab, 'push');
            app.ex6_calculateButton.ButtonPushedFcn = createCallbackFcn(app, @ex6_calculateButtonPushed, true);
            app.ex6_calculateButton.Text = 'CALCULATE';
            app.ex6_clearButton = uibutton(app.Ex6Tab, 'push');
            app.ex6_clearButton.ButtonPushedFcn = createCallbackFcn(app, @ex6_clearButtonPushed, true);
            app.ex6_clearButton.Text = 'CLEAR';
            app.TheLoadImpedanceLabel_2 = uilabel(app.Ex6Tab);
            app.TheLoadImpedanceLabel_2.Text = 'The Purely Resistive Load:';
            app.LoadRes = uieditfield(app.Ex6Tab, 'text');
            app.LoadRes.Editable = 'off';
            app.LoadRes.Placeholder = 'RLoad';
            app.TheMaximumAveragePowerLabel_2 = uilabel(app.Ex6Tab);
            app.TheMaximumAveragePowerLabel_2.Text = 'The Maximum Average Power:';
            app.maxPowRload = uieditfield(app.Ex6Tab, 'text');
            app.maxPowRload.Editable = 'off';
            app.maxPowRload.Placeholder = 'Pmax';
            app.Ex7Tab = uitab(app.TabGroup);
            app.Ex7Tab.Title = 'Ex-7';
            app.Image5 = uiimage(app.Ex7Tab);
            app.Image5.ImageSource = 'ch1-ex7.png';
            app.Label_6 = uilabel(app.Ex7Tab);
            app.Label_6.Text = {'Example 7: For the circuit shown in the figure;'; 'Calculation the rms value of current (Irms) and the average power absorbed by a resistor.'; '* Sample values;  polar form (mag,angle) --> 40,50'; '                               rectangular form --> 3-i, -5i, 14'};
            app.ex7_calculateButton = uibutton(app.Ex7Tab, 'push');
            app.ex7_calculateButton.ButtonPushedFcn = createCallbackFcn(app, @ex7_calculateButtonPushed, true);
            app.ex7_calculateButton.Text = 'CALCULATE';
            app.ValueofResistorEditFieldLabel = uilabel(app.Ex7Tab);
            app.ValueofResistorEditFieldLabel.Text = 'Value of Resistor: ';
            app.valueRes = uieditfield(app.Ex7Tab, 'text');
            app.valueRes.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.valueRes.ValueChangingFcn = createCallbackFcn(app, @polarForm, true);
            app.valueRes.Placeholder = 'Ω';
            app.TheLoadImpedanceLabel_3 = uilabel(app.Ex7Tab);
            app.TheLoadImpedanceLabel_3.Text = 'RMS value of the cureent:';
            app.Irms = uieditfield(app.Ex7Tab, 'text');
            app.Irms.Editable = 'off';
            app.Irms.Placeholder = 'Irms';
            app.TheMaximumAveragePowerLabel_3 = uilabel(app.Ex7Tab);
            app.TheMaximumAveragePowerLabel_3.Text = 'The power absorbed by resistor:';
            app.resPower = uieditfield(app.Ex7Tab, 'text');
            app.resPower.Editable = 'off';
            app.resPower.Placeholder = 'Pres';
            app.Ex8Tab = uitab(app.TabGroup);
            app.Ex8Tab.Title = 'Ex-8';
            app.Label_7 = uilabel(app.Ex8Tab);
            app.Label_7.Text = {'Example 8: For the circuit shown in the figure;'; 'Calculation the rms value of voltage (Vrms) and the average power absorbed by a resistor.'; '* Sample values;  polar form (mag,angle) --> 40,50'; '                               rectangular form --> 3-i, -5i, 14'};
            app.Image6 = uiimage(app.Ex8Tab);
            app.Image6.ImageSource = 'ch1-ex8.png';
            app.ex8_calculateButton = uibutton(app.Ex8Tab, 'push');
            app.ex8_calculateButton.ButtonPushedFcn = createCallbackFcn(app, @ex8_calculateButtonPushed, true);
            app.ex8_calculateButton.Text = 'CALCULATE';
            app.ValueofResistorEditField_2Label = uilabel(app.Ex8Tab);
            app.ValueofResistorEditField_2Label.Text = 'Value of Resistor: ';
            app.valueRes2 = uieditfield(app.Ex8Tab, 'text');
            app.valueRes2.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.valueRes2.Placeholder = 'Ω';
            app.TheLoadImpedanceLabel_4 = uilabel(app.Ex8Tab);
            app.TheLoadImpedanceLabel_4.Text = 'RMS value of the voltage:';
            app.Vrms = uieditfield(app.Ex8Tab, 'text');
            app.Vrms.Editable = 'off';
            app.Vrms.Placeholder = 'Vrms';
            app.TheMaximumAveragePowerLabel_4 = uilabel(app.Ex8Tab);
            app.TheMaximumAveragePowerLabel_4.Text = 'The power absorbed by resistor:';
            app.resPower_2 = uieditfield(app.Ex8Tab, 'text');
            app.resPower_2.Editable = 'off';
            app.resPower_2.Placeholder = 'Pres';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Chapter_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end