classdef Chapter_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        Ex1Tab                          matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        eValues                         matlab.ui.control.EditField
        PowerfactorLabel_5              matlab.ui.control.Label
        powerFactor                     matlab.ui.control.EditField
        PowerfactorLabel_4              matlab.ui.control.Label
        apparentP                       matlab.ui.control.EditField
        TheapparentpowerEditFieldLabel  matlab.ui.control.Label
        example1                        matlab.ui.control.Label
        angular                         matlab.ui.control.EditField
        ImpedanceZEditFieldLabel_2      matlab.ui.control.Label
        DONTDELETELabel                 matlab.ui.control.Label
        Impedance                       matlab.ui.control.EditField
        ImpedanceZEditFieldLabel        matlab.ui.control.Label
        Current                         matlab.ui.control.EditField
        CurrentIEditFieldLabel          matlab.ui.control.Label
        Voltage                         matlab.ui.control.EditField
        VoltageVEditFieldLabel          matlab.ui.control.Label
        ex1_clearButton                 matlab.ui.control.Button
        ex1_calculateButton             matlab.ui.control.Button
        Ex2Tab                          matlab.ui.container.Tab
        VrmsLabel_3                     matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        re2                             matlab.ui.control.EditField
        capa                            matlab.ui.control.EditField
        re1                             matlab.ui.control.EditField
        v                               matlab.ui.control.EditField
        Image                           matlab.ui.control.Image
        pFactor                         matlab.ui.control.EditField
        ThepowerfactorEditFieldLabel    matlab.ui.control.Label
        avgP                            matlab.ui.control.EditField
        TheaveragepowerEditFieldLabel   matlab.ui.control.Label
        ex1_clearButton_2               matlab.ui.control.Button
        ex2_calculateButton             matlab.ui.control.Button
        Ex3Tab                          matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        ComplexpowerEditField           matlab.ui.control.EditField
        ComplexpowerEditFieldLabel      matlab.ui.control.Label
        example3                        matlab.ui.control.Label
        LoadimpedanceEditField          matlab.ui.control.EditField
        LoadimpedanceEditFieldLabel     matlab.ui.control.Label
        PowerfactorEditField            matlab.ui.control.EditField
        PowerfactorLabel_3              matlab.ui.control.Label
        ReactivepowerEditField          matlab.ui.control.EditField
        ReactivepowerLabel              matlab.ui.control.Label
        RealpowerEditField              matlab.ui.control.EditField
        RealpowerLabel                  matlab.ui.control.Label
        ApparentpowerEditField          matlab.ui.control.EditField
        ApparentpowerEditFieldLabel     matlab.ui.control.Label
        w3                              matlab.ui.control.EditField
        ImpedanceZEditFieldLabel_4      matlab.ui.control.Label
        z3                              matlab.ui.control.EditField
        ImpedanceZEditFieldLabel_3      matlab.ui.control.Label
        i3                              matlab.ui.control.EditField
        CurrentIEditFieldLabel_2        matlab.ui.control.Label
        v3                              matlab.ui.control.EditField
        VoltageVEditFieldLabel_2        matlab.ui.control.Label
        ex3_clearButton                 matlab.ui.control.Button
        ex3_calculateButton             matlab.ui.control.Button
        Ex4Tab                          matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        Label_2                         matlab.ui.control.Label
        li                              matlab.ui.control.EditField
        TheloadimpedanceEditFieldLabel  matlab.ui.control.Label
        ap                              matlab.ui.control.EditField
        TheaveragepowerLabel            matlab.ui.control.Label
        pc                              matlab.ui.control.EditField
        ThepeakcurrentLabel             matlab.ui.control.Label
        rp                              matlab.ui.control.EditField
        ThereactivepowerEditFieldLabel  matlab.ui.control.Label
        pf4                             matlab.ui.control.EditField
        PowerFactorLabel                matlab.ui.control.Label
        v4                              matlab.ui.control.EditField
        VoltageSourceVrmsLabel          matlab.ui.control.Label
        s4                              matlab.ui.control.EditField
        ApparentPowerSLabel             matlab.ui.control.Label
        ex4_clearButton                 matlab.ui.control.Button
        ex4_calculateButton             matlab.ui.control.Button
        PowerFactorTypesButtonGroup     matlab.ui.container.ButtonGroup
        LaggingButton                   matlab.ui.control.RadioButton
        LeadingButton                   matlab.ui.control.RadioButton
        Ex5Tab                          matlab.ui.container.Tab
        VrmsLabel_4                     matlab.ui.control.Label
        LoReac                          matlab.ui.control.EditField
        ThereactivepowerEditField_3Label  matlab.ui.control.Label
        LoReal                          matlab.ui.control.EditField
        TherealpowerEditField_3Label    matlab.ui.control.Label
        LiReac                          matlab.ui.control.EditField
        ThereactivepowerEditField_2Label  matlab.ui.control.Label
        LiReal                          matlab.ui.control.EditField
        TherealpowerEditField_2Label    matlab.ui.control.Label
        Sreac                           matlab.ui.control.EditField
        ThereactivepowerEditFieldLabel_2  matlab.ui.control.Label
        Sreal                           matlab.ui.control.EditField
        TherealpowerEditFieldLabel      matlab.ui.control.Label
        ex5_clearButton                 matlab.ui.control.Button
        ex5_calculateButton             matlab.ui.control.Button
        ForloadLabel                    matlab.ui.control.Label
        ForlineLabel                    matlab.ui.control.Label
        ForsourceLabel                  matlab.ui.control.Label
        c5                              matlab.ui.control.EditField
        r5b                             matlab.ui.control.EditField
        i5                              matlab.ui.control.EditField
        r5a                             matlab.ui.control.EditField
        v5                              matlab.ui.control.EditField
        example5                        matlab.ui.control.Label
        Image2                          matlab.ui.control.Image
        Ex6Tab                          matlab.ui.container.Tab
        scomp6                          matlab.ui.control.EditField
        ThepowerfactorLabel_2           matlab.ui.control.Label
        pf6                             matlab.ui.control.EditField
        ThepowerfactorLabel             matlab.ui.control.Label
        q6                              matlab.ui.control.EditField
        ThereactivepowerLabel           matlab.ui.control.Label
        p6                              matlab.ui.control.EditField
        ThereaalpowerLabel              matlab.ui.control.Label
        s6                              matlab.ui.control.EditField
        TheapparentpowerLabel_2         matlab.ui.control.Label
        z6b                             matlab.ui.control.EditField
        Z2EditFieldLabel                matlab.ui.control.Label
        z6a                             matlab.ui.control.EditField
        Z1EditFieldLabel                matlab.ui.control.Label
        v6                              matlab.ui.control.EditField
        VrmsLabel                       matlab.ui.control.Label
        ex6_clearButton                 matlab.ui.control.Button
        ex6_calculateButton             matlab.ui.control.Button
        example6                        matlab.ui.control.Label
        Image3                          matlab.ui.control.Image
        Ex7Tab                          matlab.ui.container.Tab
        GridLayout4                     matlab.ui.container.GridLayout
        Label_3                         matlab.ui.control.Label
        ThereactivepowerVAREditFieldLabel  matlab.ui.control.Label
        CorL                            matlab.ui.control.EditField
        TheapparentpowerLabel_3         matlab.ui.control.Label
        pf7b                            matlab.ui.control.EditField
        Powerfactor2Label               matlab.ui.control.Label
        pf7a                            matlab.ui.control.EditField
        Powerfactor1Label               matlab.ui.control.Label
        q7                              matlab.ui.control.EditField
        p7                              matlab.ui.control.EditField
        TherealpowerWLabel              matlab.ui.control.Label
        f7                              matlab.ui.control.EditField
        ThefrequencyHzLabel             matlab.ui.control.Label
        v7                              matlab.ui.control.EditField
        VoltageVrmsLabel                matlab.ui.control.Label
        ex7_clearButton                 matlab.ui.control.Button
        ex7_calculateButton             matlab.ui.control.Button
        PowerFactorTypesButtonGroup_2   matlab.ui.container.ButtonGroup
        lag7                            matlab.ui.control.RadioButton
        lead7                           matlab.ui.control.RadioButton
        Ex8Tab                          matlab.ui.container.Tab
        c8                              matlab.ui.control.EditField
        r8b                             matlab.ui.control.EditField
        i8                              matlab.ui.control.EditField
        r8a                             matlab.ui.control.EditField
        v8                              matlab.ui.control.EditField
        VrmsLabel_2                     matlab.ui.control.Label
        ap8                             matlab.ui.control.EditField
        TheapparentpowerLabel_4         matlab.ui.control.Label
        ex8_clearButton                 matlab.ui.control.Button
        ex8_calculateButton             matlab.ui.control.Button
        example8                        matlab.ui.control.Label
        Image4                          matlab.ui.control.Image
    end

    
      methods (Access = private)
        % function that converts polar to rectangular form
        function rectForm = polarToRect(app, event)
            value = event.Source.Value;
            splitOperator = split(value, ',');
            if length(splitOperator) == 2
                app.DONTDELETELabel.Text = value;
                a = app.DONTDELETELabel.Text;
                a = split(a, ',');
                mag = eval(a{1,1});
                phaseAngle = eval(a{2,1});
                real = mag * cos(deg2rad(phaseAngle));
                real = round(real, 2);
                imag = mag * sin(deg2rad(phaseAngle));
                imag = round(imag, 2);
                rectForm = string(complex(real, imag));
            else
                rectForm = value;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: Current, Impedance, Voltage, c5, c5, c8, c8, 
        % capa, i3, i5, i5, i8, i8, pf4, r5a, r5a, r5b, r5b, r8a, r8a, 
        % r8b, r8b, re1, re2, s4, v, v3, v4, v5, v6, v7, v8, v8, w3, z3, 
        % z6a, z6b
        function polarForm(app, event)
            event.Source.Value = polarToRect(app, event);
        end

        % Button pushed function: ex1_calculateButton
        function ex1_calculateButtonPushed(app, event)
            % condition of voltage is none
            if app.Voltage.Value == '-'
                I = eval(app.Current.Value);
                Z = eval(app.Impedance.Value);
                V = I * Z; % the voltage

            % condition of current is none
            elseif app.Current.Value == '-'
                V = eval(app.Voltage.Value);
                Z = eval(app.Impedance.Value);
                I = V / Z; % the current

             else
                V = eval(app.Voltage.Value);
                I = eval(app.Current.Value);
                Z = V / I;
            end
            
            % calculations
            S = abs(V) * abs(I) / 2; % apparent power
            wa = eval(app.angular.Value);
            w = wa * pi;

            V_phase = rad2deg(angle(V));
            I_phase = rad2deg(angle(I));
            powFac = cos(deg2rad(V_phase - I_phase)); % power factor

            if I_phase > V_phase
                Xc = imag(Z);
                C = (-1 / (Xc * w)) * 10^6; % capacitive load
                app.powerFactor.Value = string(powFac) + ' leading';
                app.eValues.Value = 'C = ' + string(C) + ' μF';
            else 
                xL = imag(Z);
                L = (xL * w) / 10^6; % inductive load
                L = round(L, 3);
                app.powerFactor.Value = string(powFac) + ' lagging';
                app.eValues.Value = 'L = ' + string(L) + ' H';
            end

            app.apparentP.Value = string(S) + ' VA';
        end

        % Button pushed function: ex1_clearButton
        function ex1_clearButtonPushed(app, event)
            app.Voltage.Value = '';
            app.Current.Value = '';
            app.Impedance.Value = '';
            app.angular.Value = '';
            app.apparentP.Value = '';
            app.powerFactor.Value = '';
            app.eValues.Value = '';
        end

        % Button pushed function: ex2_calculateButton
        function ex2_calculateButtonPushed(app, event)
            % input values
            volt = eval(app.v.Value);
            res1 = eval(app.re1.Value);
            res2 = eval(app.re2.Value);
            cap = eval(app.capa.Value);

            % calculations
            Z = res1 + (res2 * cap) / (res2 + cap); % impedance
            Zphase = rad2deg(angle(Z));
            powFac = cos(deg2rad(Zphase)); % power factor
            I = volt / Z;
            P = abs(volt) * abs(I) * powFac;

            if imag(Z) < 0
                app.pFactor.Value = string(powFac) + ' leading';
            else 
                app.pFactor.Value = string(powFac) + ' lagging';
            end

            app.avgP.Value = string(P) + ' Watt';
        end

        % Button pushed function: ex1_clearButton_2
        function ex1_clearButton_2Pushed(app, event)
            app.v.Value = '';
            app.re1.Value = '';
            app.capa.Value = '';
            app.re2.Value = '';
            app.avgP.Value = '';
            app.pFactor.Value = '';
        end

        % Button pushed function: ex3_calculateButton
        function ex3_calculateButtonPushed(app, event)
            % condition of voltage is none
            if app.v3.Value == '-'
                I = eval(app.i3.Value);
                Z = eval(app.z3.Value);
                V = I * Z; % the voltage

            % condition of current is none
            elseif app.i3.Value == '-'
                V = eval(app.v3.Value);
                Z = eval(app.z3.Value);
                I = V / Z; % the current

             else
                V = eval(app.v3.Value);
                I = eval(app.i3.Value);
                Z = V / I;
            end
            
            % calculations
            S = V * conj(I) / 2; % complex power
            Sap = abs(S); % apparent power
            P = real(S); % real power
            Q = imag(S); % reactive power
            Sphase = rad2deg(angle(S));
            pf = cos(deg2rad(Sphase)); % power factor

            app.ComplexpowerEditField.Value = string(S) + ' VA';
            app.ApparentpowerEditField.Value = string(Sap) + ' VA';
            app.RealpowerEditField.Value = string(P) + ' Watt';
            app.ReactivepowerEditField.Value = string(Q) + ' VAR';
            app.LoadimpedanceEditField.Value = string(Z) + ' Ω';
            
            if Q < 0
                app.PowerfactorEditField.Value = string(pf) + ' leading';
            else
                app.PowerfactorEditField.Value = string(pf) + ' lagging';
            end
        end

        % Button pushed function: ex3_clearButton
        function ex3_clearButtonPushed(app, event)
            app.v3.Value = '';
            app.i3.Value = '';
            app.z3.Value = '';
            app.w3.Value = '';
            app.ComplexpowerEditField.Value = '';
            app.ApparentpowerEditField.Value = '';
            app.RealpowerEditField.Value = '';
            app.ReactivepowerEditField.Value = '';
            app.PowerfactorEditField.Value = '';
            app.LoadimpedanceEditField.Value = '';
        end

        % Button pushed function: ex4_calculateButton
        function ex4_calculateButtonPushed(app, event)
            S = eval(app.s4.Value); % apparent power
            Vrms = eval(app.v4.Value); % Vrms value
            pf = eval(app.pf4.Value);
            
            % leading or lagging
            if app.LeadingButton.Value == 1
                pf = -rad2deg(acos(pf));
            elseif app.LaggingButton.Value == 1
                pf = -(360 - rad2deg(acos(pf)));
            end
            
            % calculations
            P = S * cos(deg2rad(pf)); % average or real power
            Q = S * sin(deg2rad(pf)); % reactive power
            Scomplex = complex(P, Q); % complex power

            Irms = Scomplex / Vrms; % Irms
            Ipeak = sqrt(2) * abs(Irms); % peak current
            
            Z = Vrms / conj(Irms);
            Z = round(Z, 3);
            app.ap.Value = string(P / 1000) + ' kWatt';
            app.rp.Value = string(Q) + ' KVA';
            app.pc.Value = string(Ipeak) + ' A';
            app.li.Value = string(Z) + ' Ω';
        end

        % Button pushed function: ex4_clearButton
        function ex4_clearButtonPushed(app, event)
            app.s4.Value = '';
            app.v4.Value = '';
            app.pf4.Value = '';
            app.ap.Value = '';
            app.rp.Value = '';
            app.pc.Value = '';
            app.li.Value = '';
        end

        % Button pushed function: ex5_calculateButton
        function ex5_calculateButtonPushed(app, event)
            % input values
            V = eval(app.v5.Value);
            r1 = eval(app.r5a.Value);
            r2 = eval(app.r5b.Value);
            ind = eval(app.i5.Value);
            cap = eval(app.c5.Value);
            
            % calculations
            Z = r1 + r2 + ind + cap; % total impedance
            I = V / Z; % current

            Ss = V * conj(I); % complex power for the source
            Vli = (r1 + ind) * I;
            Sli = Vli * conj(I); % complex power for the line
            Vlo = (r2 + cap) * I;
            Slo = Vlo * conj(I); % complex power for the load
            
            % print operations
            app.Sreal.Value = string(round(real(Ss),1)) + ' Watt';
            app.LiReal.Value = string(round(real(Sli),1)) + ' Watt';
            app.LoReal.Value = string(round(real(Slo),1)) + ' Watt';

            if imag(Ss) < 0
                app.Sreac.Value = string(round(imag(Ss),1)) + ' VAR leading';
            else
                app.Sreac.Value = string(round(imag(Ss),1)) + ' VAR lagging';
            end

            if imag(Sli) < 0
                app.LiReac.Value = string(round(imag(Sli),1)) + ' VAR leading';
            else
                app.LiReac.Value = string(round(imag(Sli),1)) + ' VAR lagging';
            end

            if imag(Slo) < 0
                app.LoReac.Value = string(round(imag(Slo),1)) + ' VAR leading';
            else
                app.LoReac.Value = string(round(imag(Slo),1)) + ' VAR lagging';
            end
        end

        % Button pushed function: ex5_clearButton
        function ex5_clearButtonPushed(app, event)
            app.v5.Value = '';
            app.r5a.Value = '';
            app.r5b.Value = '';
            app.i5.Value = '';
            app.c5.Value = '';
            app.Sreal.Value = '';
            app.Sreac.Value = '';
            app.LiReal.Value = '';
            app.LiReac.Value = '';
            app.LoReal.Value = '';
            app.LoReac.Value = '';
        end

        % Button pushed function: ex6_calculateButton
        function ex6_calculateButtonPushed(app, event)
            % input values
            V = eval(app.v6.Value);
            Z1 = eval(app.z6a.Value);
            Z2 = eval(app.z6b.Value);

            % calculations
            I1 = V / Z1; % current of Z1
            I2 = V / Z2; % current of Z2

            S1 = abs(V)^2 / conj(Z1); % complex power of Z1
            S2 = abs(V)^2 / conj(Z2); % complex power of Z2
            St = S1 + S2; % total complex power

            Papp = round(abs(St),2); % total apparent power
            Preal = round(real(St),2); % total real power
            Preac = round(imag(St),2); % total reactive power

            pf = Preal / Papp; % power factor
            It = I1 + I2; % total current
            Ss = V * conj(It); % complex power supplied by source
            Ss = round(Ss, 2);
            
            % print operations
            app.s6.Value = string(Papp) + ' VA';
            app.p6.Value = string(Preal) + ' Watt';
            app.q6.Value = string(Preac);
            app.scomp6.Value = string(Ss) + ' VA';
            if Preac < 0
                 app.pf6.Value = string(pf) + ' leading';
            else
                app.pf6.Value = string(pf) + ' lagging';
            end
        end

        % Button pushed function: ex6_clearButton
        function ex6_clearButtonPushed(app, event)
            app.v6.Value = '';
            app.z6a.Value = '';
            app.z6b.Value = '';
            app.s6.Value = '';
            app.p6.Value = '';
            app.q6.Value = '';
            app.pf6.Value = '';
            app.scomp6.Value = '';
        end

        % Button pushed function: ex7_calculateButton
        function ex7_calculateButtonPushed(app, event)
            % input values
            V = eval(app.v7.Value);
            f = eval(app.f7.Value);
            pf1 = eval(app.pf7a.Value);
            pf2 = eval(app.pf7b.Value);
            
            % calculations
            % pf lagging --> Capacitive
            if app.lag7.Value == 1
                pfnew = rad2deg(acos(pf1));
                if app.q7.Value == '-'
                    p = eval(app.p7.Value);
                    S1 = p / pf1; % apparent power with pf1
                    Q1 = S1 * sin(deg2rad(pfnew)); % reactive power
                    
                    S2 = p / pf2; % apparent power with pf2
                    Q2 = S2 * sin(deg2rad(rad2deg(acos(pf2)))); % reactive power

                elseif app.p7.Value == '-'
                    q = eval(app.q7.Value);
                    S1 = q / sin(deg2rad(pfnew)); % apparent power with pf2
                    Q1 = S1 * sin(deg2rad(rad2deg(acos(pf1)))); % real power
                    
                    S2 = q / sin(deg2rad(pfnew)); % apparent power with pf2
                    Q2 = S2 * sin(deg2rad(rad2deg(acos(pf2)))); % real power
                end
                
                % capative
                Qc = Q1 - Q2;
                w = 2 * pi;
                C = Qc / (w * f * abs(V)^2); % capacitive
                C = round((C * 10^6), 2);
                app.CorL.Value = 'C = ' + string(C) + ' μF';
            
            % pf leading --> Inductive
            elseif app.lead7.Value == 1
                pfnew = 360 - rad2deg(acos(pf1));
                if app.q7.Value == '-'
                    p = eval(app.p7.Value);
                    S1 = p / pf1; % apparent power with pf1
                    Q1 = S1 * sin(deg2rad(pfnew)); % reactive power
                    
                    S2 = p / pf2; % apparent power with pf2
                    Q2 = S2 * sin(deg2rad(rad2deg(acos(pf2)))); % reactive power

                elseif app.p7.Value == '-'
                    q = eval(app.q7.Value);
                    S1 = q / sin(deg2rad(pfnew)); % apparent power with pf2
                    Q1 = S1 * sin(deg2rad(rad2deg(acos(pf1)))); % real power
                    
                    S2 = q / sin(deg2rad(pfnew)); % apparent power with pf2
                    Q2 = S2 * sin(deg2rad(rad2deg(acos(pf2)))); % real power
                end

                % inductive
                Ql = Q1 - Q2;
                w = 2 * pi;
                L = abs(V^2) / (w * Ql);
                L = round((L), 2);
                app.CorL.Value = 'L = ' + string(L) + ' H';
            end
        end

        % Button pushed function: ex7_clearButton
        function ex7_clearButtonPushed(app, event)
            app.v7.Value = '';
            app.f7.Value = '';
            app.p7.Value = '';
            app.q7.Value = '';
            app.pf7a.Value = '';
            app.pf7b.Value = '';
            app.CorL.Value = '';
        end

        % Button pushed function: ex8_calculateButton
        function ex8_calculateButtonPushed(app, event)
            % input values
            V = eval(app.v8.Value);
            r1 = eval(app.r8a.Value);
            r2 = eval(app.r8b.Value);
            ind = eval(app.i8.Value);
            cap = eval(app.c8.Value);
            
            % calculations
            Z = r1 + r2 + ind + cap; % total impedance
            Irms = V / Z; % current rms
            Vrms = (r2 + cap) * Irms;
            S = Vrms * conj(Irms); % complex power
            P = real(S);

            % print operations
            app.ap8.Value = string(P) + ' Watt';
        end

        % Button pushed function: ex8_clearButton
        function ex8_clearButtonPushed(app, event)
            app.v8.Value = '';
            app.r8a.Value = '';
            app.r8b.Value = '';
            app.i8.Value = '';
            app.c8.Value = '';
            app.ap8.Value = '';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Ex1Tab = uitab(app.TabGroup);
            app.Ex1Tab.Title = 'Ex-1';
            app.GridLayout = uigridlayout(app.Ex1Tab);
            app.ex1_calculateButton = uibutton(app.GridLayout, 'push');
            app.ex1_calculateButton.ButtonPushedFcn = createCallbackFcn(app, @ex1_calculateButtonPushed, true);
            app.ex1_calculateButton.Text = 'CALCULATE';
            app.ex1_clearButton = uibutton(app.GridLayout, 'push');
            app.ex1_clearButton.ButtonPushedFcn = createCallbackFcn(app, @ex1_clearButtonPushed, true);
            app.ex1_clearButton.Text = 'CLEAR';
            app.VoltageVEditFieldLabel = uilabel(app.GridLayout);
            app.VoltageVEditFieldLabel.Text = 'Voltage (V): ';
            app.Voltage = uieditfield(app.GridLayout, 'text');
            app.Voltage.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.Voltage.Placeholder = 'V';
            app.CurrentIEditFieldLabel = uilabel(app.GridLayout);
            app.CurrentIEditFieldLabel.Text = 'Current (I): ';
            app.Current = uieditfield(app.GridLayout, 'text');
            app.Current.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.Current.Placeholder = 'A';
            app.ImpedanceZEditFieldLabel = uilabel(app.GridLayout);
            app.ImpedanceZEditFieldLabel.Text = 'Impedance(Z): ';
            app.Impedance = uieditfield(app.GridLayout, 'text');
            app.Impedance.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.Impedance.Placeholder = 'Ω';
            app.DONTDELETELabel = uilabel(app.GridLayout);
            app.DONTDELETELabel.Visible = 'off';
            app.DONTDELETELabel.Text = 'DONT DELETE!';
            app.ImpedanceZEditFieldLabel_2 = uilabel(app.GridLayout);
            app.ImpedanceZEditFieldLabel_2.Text = 'Angular (w): ';
            app.angular = uieditfield(app.GridLayout, 'text');
            app.angular.Placeholder = 'w';
            app.example1 = uilabel(app.GridLayout);
            app.example1.Text = {'Example 1: A series-connected load drives a cuurent i'; 'when the applied voltage is v.'; '- Calculation the apparent power and the power factor of '; 'the load. '; '- Determination of the element values that form the series'; 'connected load.'; '* Sample values;  3+4i, -5i, 10'; '         if value is 10cos(22t-40) ---> mag:10, angle:-40 and w:22'; '         if value is polar form, (mag, angle) ---> 20, 30'; '         if value is none ---> -'};
            app.TheapparentpowerEditFieldLabel = uilabel(app.GridLayout);
            app.TheapparentpowerEditFieldLabel.Text = 'The apparent power:';
            app.apparentP = uieditfield(app.GridLayout, 'text');
            app.apparentP.Editable = 'off';
            app.apparentP.Placeholder = 'S';
            app.PowerfactorLabel_4 = uilabel(app.GridLayout);
            app.PowerfactorLabel_4.Text = 'Power factor:';
            app.powerFactor = uieditfield(app.GridLayout, 'text');
            app.powerFactor.Editable = 'off';
            app.powerFactor.Placeholder = 'pf';
            app.PowerfactorLabel_5 = uilabel(app.GridLayout);
            app.PowerfactorLabel_5.Text = 'Element values:';
            app.eValues = uieditfield(app.GridLayout, 'text');
            app.eValues.Editable = 'off';
            app.eValues.Placeholder = 'C or L';
            app.Ex2Tab = uitab(app.TabGroup);
            app.Ex2Tab.Title = 'Ex-2';
            app.ex2_calculateButton = uibutton(app.Ex2Tab, 'push');
            app.ex2_calculateButton.ButtonPushedFcn = createCallbackFcn(app, @ex2_calculateButtonPushed, true);
            app.ex2_calculateButton.Text = 'CALCULATE';
            app.ex1_clearButton_2 = uibutton(app.Ex2Tab, 'push');
            app.ex1_clearButton_2.ButtonPushedFcn = createCallbackFcn(app, @ex1_clearButton_2Pushed, true);
            app.ex1_clearButton_2.Text = 'CLEAR';
            app.TheaveragepowerEditFieldLabel = uilabel(app.Ex2Tab);
            app.TheaveragepowerEditFieldLabel.Text = 'The average power:';
            app.avgP = uieditfield(app.Ex2Tab, 'text');
            app.avgP.Editable = 'off';
            app.avgP.Placeholder = 'Pavg';
            app.ThepowerfactorEditFieldLabel = uilabel(app.Ex2Tab);
            app.ThepowerfactorEditFieldLabel.Text = 'The power factor:';
            app.pFactor = uieditfield(app.Ex2Tab, 'text');
            app.pFactor.Editable = 'off';
            app.pFactor.Placeholder = 'pf';
            app.Image = uiimage(app.Ex2Tab);
            app.Image.ImageSource = 'ch2-ex2.png';
            app.v = uieditfield(app.Ex2Tab, 'text');
            app.v.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.v.Placeholder = 'V';
            app.re1 = uieditfield(app.Ex2Tab, 'text');
            app.re1.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.re1.Placeholder = 'Ω';
            app.capa = uieditfield(app.Ex2Tab, 'text');
            app.capa.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.capa.Placeholder = 'Ω';
            app.re2 = uieditfield(app.Ex2Tab, 'text');
            app.re2.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.re2.Placeholder = 'Ω';
            app.Label = uilabel(app.Ex2Tab);
            app.Label.Text = {'Example 2: Calculation the power factor '; 'of the entire circuit of Fig. as seen by the'; 'source. What is the average power'; 'supplied by the source?'; '(Hint: value of voltage should be rms value)'; '* Sample values;  3+4i, -5i, 10'; 'if value is polar form; '; '(mag, angle) ---> 20, 30'};
            app.VrmsLabel_3 = uilabel(app.Ex2Tab);
            app.VrmsLabel_3.Text = 'Vrms: ';
            app.Ex3Tab = uitab(app.TabGroup);
            app.Ex3Tab.Title = 'Ex-3';
            app.GridLayout2 = uigridlayout(app.Ex3Tab);
            app.ex3_calculateButton = uibutton(app.GridLayout2, 'push');
            app.ex3_calculateButton.ButtonPushedFcn = createCallbackFcn(app, @ex3_calculateButtonPushed, true);
            app.ex3_calculateButton.Text = 'CALCULATE';
            app.ex3_clearButton = uibutton(app.GridLayout2, 'push');
            app.ex3_clearButton.ButtonPushedFcn = createCallbackFcn(app, @ex3_clearButtonPushed, true);
            app.ex3_clearButton.Text = 'CLEAR';
            app.VoltageVEditFieldLabel_2 = uilabel(app.GridLayout2);
            app.VoltageVEditFieldLabel_2.Text = 'Voltage (V): ';
            app.v3 = uieditfield(app.GridLayout2, 'text');
            app.v3.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.v3.Placeholder = 'V';
            app.CurrentIEditFieldLabel_2 = uilabel(app.GridLayout2);
            app.CurrentIEditFieldLabel_2.Text = 'Current (I): ';
            app.i3 = uieditfield(app.GridLayout2, 'text');
            app.i3.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.i3.Placeholder = 'A';
            app.ImpedanceZEditFieldLabel_3 = uilabel(app.GridLayout2);
            app.ImpedanceZEditFieldLabel_3.Text = 'Impedance(Z): ';
            app.z3 = uieditfield(app.GridLayout2, 'text');
            app.z3.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.z3.Placeholder = 'Ω';
            app.ImpedanceZEditFieldLabel_4 = uilabel(app.GridLayout2);
            app.ImpedanceZEditFieldLabel_4.Text = 'Angular (w): ';
            app.w3 = uieditfield(app.GridLayout2, 'text');
            app.w3.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.w3.Placeholder = 'w';
            app.ApparentpowerEditFieldLabel = uilabel(app.GridLayout2);
            app.ApparentpowerEditFieldLabel.Text = 'Apparent power:';
            app.ApparentpowerEditField = uieditfield(app.GridLayout2, 'text');
            app.ApparentpowerEditField.Editable = 'off';
            app.ApparentpowerEditField.Placeholder = 'Sapp';
            app.RealpowerLabel = uilabel(app.GridLayout2);
            app.RealpowerLabel.Text = 'Real power:';
            app.RealpowerEditField = uieditfield(app.GridLayout2, 'text');
            app.RealpowerEditField.Editable = 'off';
            app.RealpowerEditField.Placeholder = 'P';
            app.ReactivepowerLabel = uilabel(app.GridLayout2);
            app.ReactivepowerLabel.Text = 'Reactive power:';
            app.ReactivepowerEditField = uieditfield(app.GridLayout2, 'text');
            app.ReactivepowerEditField.Editable = 'off';
            app.ReactivepowerEditField.Placeholder = 'Q';
            app.PowerfactorLabel_3 = uilabel(app.GridLayout2);
            app.PowerfactorLabel_3.Text = 'Power factor:';
            app.PowerfactorEditField = uieditfield(app.GridLayout2, 'text');
            app.PowerfactorEditField.Editable = 'off';
            app.PowerfactorEditField.Placeholder = 'pf';
            app.LoadimpedanceEditFieldLabel = uilabel(app.GridLayout2);
            app.LoadimpedanceEditFieldLabel.Text = 'Load impedance:';
            app.LoadimpedanceEditField = uieditfield(app.GridLayout2, 'text');
            app.LoadimpedanceEditField.Editable = 'off';
            app.LoadimpedanceEditField.Placeholder = 'Z';
            app.example3 = uilabel(app.GridLayout2);
            app.example3.Text = {'Example 3: The voltage across a load is voltage V '; 'and the  current through the element in the '; 'direction  of the voltage drop is I. '; 'Calculation;'; '- The complex, apparent, real and reactive powers'; '- The power factor and the load impedance.'; '* Sample values;  3+4i, -5i, 10'; '   if value is polar form, (mag, angle) ---> 20, 30'; '   if value is 10cos(22t-40) ---> mag:10, ang:-40 w:22'; '   if value is none ---> -'};
            app.ComplexpowerEditFieldLabel = uilabel(app.GridLayout2);
            app.ComplexpowerEditFieldLabel.Text = 'Complex power:';
            app.ComplexpowerEditField = uieditfield(app.GridLayout2, 'text');
            app.ComplexpowerEditField.Editable = 'off';
            app.ComplexpowerEditField.Placeholder = 'Scomp';
            app.Ex4Tab = uitab(app.TabGroup);
            app.Ex4Tab.Title = 'Ex-4';
            app.GridLayout3 = uigridlayout(app.Ex4Tab);
            app.PowerFactorTypesButtonGroup = uibuttongroup(app.GridLayout3);
            app.PowerFactorTypesButtonGroup.Title = 'Power Factor Types';
            app.LeadingButton = uiradiobutton(app.PowerFactorTypesButtonGroup);
            app.LeadingButton.Text = 'Leading';
            app.LeadingButton.Value = true;
            app.LaggingButton = uiradiobutton(app.PowerFactorTypesButtonGroup);
            app.LaggingButton.Text = 'Lagging';
            app.ex4_calculateButton = uibutton(app.GridLayout3, 'push');
            app.ex4_calculateButton.ButtonPushedFcn = createCallbackFcn(app, @ex4_calculateButtonPushed, true);
            app.ex4_calculateButton.Text = 'CALCULATE';
            app.ex4_clearButton = uibutton(app.GridLayout3, 'push');
            app.ex4_clearButton.ButtonPushedFcn = createCallbackFcn(app, @ex4_clearButtonPushed, true);
            app.ex4_clearButton.Text = 'CLEAR';
            app.ApparentPowerSLabel = uilabel(app.GridLayout3);
            app.ApparentPowerSLabel.Text = 'Apparent Power (S): ';
            app.s4 = uieditfield(app.GridLayout3, 'text');
            app.s4.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.s4.Placeholder = 'VA';
            app.VoltageSourceVrmsLabel = uilabel(app.GridLayout3);
            app.VoltageSourceVrmsLabel.Text = 'Voltage Source (Vrms): ';
            app.v4 = uieditfield(app.GridLayout3, 'text');
            app.v4.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.v4.Placeholder = 'V';
            app.PowerFactorLabel = uilabel(app.GridLayout3);
            app.PowerFactorLabel.Text = 'Power Factor: ';
            app.pf4 = uieditfield(app.GridLayout3, 'text');
            app.pf4.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.pf4.Placeholder = 'pf';
            app.ThereactivepowerEditFieldLabel = uilabel(app.GridLayout3);
            app.ThereactivepowerEditFieldLabel.Text = 'The reactive power:';
            app.rp = uieditfield(app.GridLayout3, 'text');
            app.rp.Editable = 'off';
            app.rp.Placeholder = 'Q';
            app.ThepeakcurrentLabel = uilabel(app.GridLayout3);
            app.ThepeakcurrentLabel.Text = 'The peak current:';
            app.pc = uieditfield(app.GridLayout3, 'text');
            app.pc.Editable = 'off';
            app.pc.Placeholder = 'I';
            app.TheaveragepowerLabel = uilabel(app.GridLayout3);
            app.TheaveragepowerLabel.Text = 'The average power:';
            app.ap = uieditfield(app.GridLayout3, 'text');
            app.ap.Editable = 'off';
            app.ap.Placeholder = 'Pavg';
            app.TheloadimpedanceEditFieldLabel = uilabel(app.GridLayout3);
            app.TheloadimpedanceEditFieldLabel.Text = 'The load impedance:';
            app.li = uieditfield(app.GridLayout3, 'text');
            app.li.Editable = 'off';
            app.li.Placeholder = 'Z';
            app.Label_2 = uilabel(app.GridLayout3);
            app.Label_2.Text = {'Example 4: A load Z draws with apparent power'; 'at a power factor from a Vrms sinusoidal source. '; 'Calculation:'; '- The average and reactive powers to the load,'; '- The peak current, and'; '- The load impedance'; '* Sample values;  3+4i, -5i, 10'; '    if value is polar form; (mag, angle) ---> 20, 30'};
            app.Ex5Tab = uitab(app.TabGroup);
            app.Ex5Tab.Title = 'Ex-5';
            app.Image2 = uiimage(app.Ex5Tab);
            app.Image2.ImageSource = 'ch2-ex5.png';
            app.example5 = uilabel(app.Ex5Tab);
            app.example5.Text = {'Example 5: Calculation the real power and reactive power absorbed by '; 'the source, the line, and the load.'; '* If value is polar form, (mag, angle) ---> 20, 30'};
            app.v5 = uieditfield(app.Ex5Tab, 'text');
            app.v5.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.v5.Placeholder = 'V';
            app.r5a = uieditfield(app.Ex5Tab, 'text');
            app.r5a.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.r5a.ValueChangingFcn = createCallbackFcn(app, @polarForm, true);
            app.r5a.Placeholder = 'Ω';
            app.i5 = uieditfield(app.Ex5Tab, 'text');
            app.i5.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.i5.ValueChangingFcn = createCallbackFcn(app, @polarForm, true);
            app.i5.Placeholder = 'Ω';
            app.r5b = uieditfield(app.Ex5Tab, 'text');
            app.r5b.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.r5b.ValueChangingFcn = createCallbackFcn(app, @polarForm, true);
            app.r5b.Placeholder = 'Ω';
            app.c5 = uieditfield(app.Ex5Tab, 'text');
            app.c5.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.c5.ValueChangingFcn = createCallbackFcn(app, @polarForm, true);
            app.c5.Placeholder = 'Ω';
            app.ForsourceLabel = uilabel(app.Ex5Tab);
            app.ForsourceLabel.Text = 'For source;';
            app.ForlineLabel = uilabel(app.Ex5Tab);
            app.ForlineLabel.Text = 'For line;';
            app.ForloadLabel = uilabel(app.Ex5Tab);
            app.ForloadLabel.Text = 'For load;';
            app.ex5_calculateButton = uibutton(app.Ex5Tab, 'push');
            app.ex5_calculateButton.ButtonPushedFcn = createCallbackFcn(app, @ex5_calculateButtonPushed, true);
            app.ex5_calculateButton.Text = 'CALCULATE';
            app.ex5_clearButton = uibutton(app.Ex5Tab, 'push');
            app.ex5_clearButton.ButtonPushedFcn = createCallbackFcn(app, @ex5_clearButtonPushed, true);
            app.ex5_clearButton.Text = 'CLEAR';
            app.TherealpowerEditFieldLabel = uilabel(app.Ex5Tab);
            app.TherealpowerEditFieldLabel.Text = 'The real power:';
            app.Sreal = uieditfield(app.Ex5Tab, 'text');
            app.Sreal.Editable = 'off';
            app.Sreal.Placeholder = 'P';
            app.ThereactivepowerEditFieldLabel_2 = uilabel(app.Ex5Tab);
            app.ThereactivepowerEditFieldLabel_2.Text = 'The reactive power:';
            app.Sreac = uieditfield(app.Ex5Tab, 'text');
            app.Sreac.Editable = 'off';
            app.Sreac.Placeholder = 'Q';
            app.TherealpowerEditField_2Label = uilabel(app.Ex5Tab);
            app.TherealpowerEditField_2Label.Text = 'The real power:';
            app.LiReal = uieditfield(app.Ex5Tab, 'text');
            app.LiReal.Editable = 'off';
            app.LiReal.Placeholder = 'P';
            app.ThereactivepowerEditField_2Label = uilabel(app.Ex5Tab);
            app.ThereactivepowerEditField_2Label.Text = 'The reactive power:';
            app.LiReac = uieditfield(app.Ex5Tab, 'text');
            app.LiReac.Editable = 'off';
            app.LiReac.Placeholder = 'Q';
            app.TherealpowerEditField_3Label = uilabel(app.Ex5Tab);
            app.TherealpowerEditField_3Label.Text = 'The real power:';
            app.LoReal = uieditfield(app.Ex5Tab, 'text');
            app.LoReal.Editable = 'off';
            app.LoReal.Placeholder = 'P';
            app.ThereactivepowerEditField_3Label = uilabel(app.Ex5Tab);
            app.ThereactivepowerEditField_3Label.Text = 'The reactive power:';
            app.LoReac = uieditfield(app.Ex5Tab, 'text');
            app.LoReac.Editable = 'off';
            app.LoReac.Placeholder = 'Q';
            app.VrmsLabel_4 = uilabel(app.Ex5Tab);
            app.VrmsLabel_4.Text = 'Vrms: ';
            app.Ex6Tab = uitab(app.TabGroup);
            app.Ex6Tab.Title = 'Ex-6';
            app.Image3 = uiimage(app.Ex6Tab);
            app.Image3.ImageSource = 'ch2-ex6.png';
            app.example6 = uilabel(app.Ex6Tab);
            app.example6.Text = {'Example 6: Calculation the total; apparent power, real power, reactive power, power factor,'; 'and supplied by the source and seen by the source. (Hint: value of voltage should be rms value)'; '* Sample values;  3+4i, -5i, 10. If value is polar form; (mag, angle) ---> 20, 30'};
            app.ex6_calculateButton = uibutton(app.Ex6Tab, 'push');
            app.ex6_calculateButton.ButtonPushedFcn = createCallbackFcn(app, @ex6_calculateButtonPushed, true);
            app.ex6_calculateButton.Text = 'CALCULATE';
            app.ex6_clearButton = uibutton(app.Ex6Tab, 'push');
            app.ex6_clearButton.ButtonPushedFcn = createCallbackFcn(app, @ex6_clearButtonPushed, true);
            app.ex6_clearButton.Text = 'CLEAR';
            app.VrmsLabel = uilabel(app.Ex6Tab);
            app.VrmsLabel.Text = 'Vrms:';
            app.v6 = uieditfield(app.Ex6Tab, 'text');
            app.v6.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.v6.Placeholder = 'V';
            app.Z1EditFieldLabel = uilabel(app.Ex6Tab);
            app.Z1EditFieldLabel.Text = 'Z1:';
            app.z6a = uieditfield(app.Ex6Tab, 'text');
            app.z6a.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.z6a.Placeholder = 'Ω';
            app.Z2EditFieldLabel = uilabel(app.Ex6Tab);
            app.Z2EditFieldLabel.Text = 'Z2:';
            app.z6b = uieditfield(app.Ex6Tab, 'text');
            app.z6b.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.z6b.Placeholder = 'Ω';
            app.TheapparentpowerLabel_2 = uilabel(app.Ex6Tab);
            app.TheapparentpowerLabel_2.Text = 'The apparent power:';
            app.s6 = uieditfield(app.Ex6Tab, 'text');
            app.s6.Editable = 'off';
            app.s6.Placeholder = 'S';
            app.ThereaalpowerLabel = uilabel(app.Ex6Tab);
            app.ThereaalpowerLabel.Text = 'The reaal power:';
            app.p6 = uieditfield(app.Ex6Tab, 'text');
            app.p6.Editable = 'off';
            app.p6.Placeholder = 'P';
            app.ThereactivepowerLabel = uilabel(app.Ex6Tab);
            app.ThereactivepowerLabel.Text = 'The reactive power:';
            app.q6 = uieditfield(app.Ex6Tab, 'text');
            app.q6.Editable = 'off';
            app.q6.Placeholder = 'Q';
            app.ThepowerfactorLabel = uilabel(app.Ex6Tab);
            app.ThepowerfactorLabel.Text = 'The power factor:';
            app.pf6 = uieditfield(app.Ex6Tab, 'text');
            app.pf6.Editable = 'off';
            app.pf6.Placeholder = 'pf';
            app.ThepowerfactorLabel_2 = uilabel(app.Ex6Tab);
            app.ThepowerfactorLabel_2.Text = 'The complex power by the source:';
            app.scomp6 = uieditfield(app.Ex6Tab, 'text');
            app.scomp6.Editable = 'off';
            app.scomp6.Placeholder = 'Scomp';
            app.Ex7Tab = uitab(app.TabGroup);
            app.Ex7Tab.Title = 'Ex-7';
            app.GridLayout4 = uigridlayout(app.Ex7Tab);
            app.PowerFactorTypesButtonGroup_2 = uibuttongroup(app.GridLayout4);
            app.PowerFactorTypesButtonGroup_2.Title = 'Power Factor Types';
            app.lead7 = uiradiobutton(app.PowerFactorTypesButtonGroup_2);
            app.lead7.Text = 'Leading';
            app.lead7.Value = true;
            app.lag7 = uiradiobutton(app.PowerFactorTypesButtonGroup_2);
            app.lag7.Text = 'Lagging';
            app.ex7_calculateButton = uibutton(app.GridLayout4, 'push');
            app.ex7_calculateButton.ButtonPushedFcn = createCallbackFcn(app, @ex7_calculateButtonPushed, true);
            app.ex7_calculateButton.Text = 'CALCULATE';
            app.ex7_clearButton = uibutton(app.GridLayout4, 'push');
            app.ex7_clearButton.ButtonPushedFcn = createCallbackFcn(app, @ex7_clearButtonPushed, true);
            app.ex7_clearButton.Text = 'CLEAR';
            app.VoltageVrmsLabel = uilabel(app.GridLayout4);
            app.VoltageVrmsLabel.Text = 'Voltage (Vrms): ';
            app.v7 = uieditfield(app.GridLayout4, 'text');
            app.v7.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.v7.Placeholder = 'V';
            app.ThefrequencyHzLabel = uilabel(app.GridLayout4);
            app.ThefrequencyHzLabel.Text = 'The frequency (Hz): ';
            app.f7 = uieditfield(app.GridLayout4, 'text');
            app.f7.Placeholder = 'f';
            app.TherealpowerWLabel = uilabel(app.GridLayout4);
            app.TherealpowerWLabel.Text = 'The real power (W): ';
            app.p7 = uieditfield(app.GridLayout4, 'text');
            app.p7.Placeholder = 'P';
            app.q7 = uieditfield(app.GridLayout4, 'text');
            app.q7.Placeholder = 'Q';
            app.Powerfactor1Label = uilabel(app.GridLayout4);
            app.Powerfactor1Label.Text = 'Power factor 1: ';
            app.pf7a = uieditfield(app.GridLayout4, 'text');
            app.pf7a.Placeholder = 'pf1';
            app.Powerfactor2Label = uilabel(app.GridLayout4);
            app.Powerfactor2Label.Text = 'Power factor 2: ';
            app.pf7b = uieditfield(app.GridLayout4, 'text');
            app.pf7b.Placeholder = 'pf2';
            app.TheapparentpowerLabel_3 = uilabel(app.GridLayout4);
            app.TheapparentpowerLabel_3.Text = 'Capacitive or Inductive: ';
            app.CorL = uieditfield(app.GridLayout4, 'text');
            app.CorL.Editable = 'off';
            app.CorL.Placeholder = 'C or L';
            app.ThereactivepowerVAREditFieldLabel = uilabel(app.GridLayout4);
            app.ThereactivepowerVAREditFieldLabel.Text = 'The reactive power (VAR): ';
            app.Label_3 = uilabel(app.GridLayout4);
            app.Label_3.Text = {'Example 4: Calculation the value of '; 'capacitance or inductance.'; '* Sample values;  3+4i, -5i, 10'; 'if value is polar form; '; '(mag, angle) ---> 20, 30'; 'if P or Q is none ---> -'};
            app.Ex8Tab = uitab(app.TabGroup);
            app.Ex8Tab.Title = 'Ex-8';
            app.Image4 = uiimage(app.Ex8Tab);
            app.Image4.ImageSource = 'ch2-ex8.png';
            app.example8 = uilabel(app.Ex8Tab);
            app.example8.Text = {'Example 6: Calculation the total; apparent power, real power, reactive power, power factor,'; 'and supplied by the source and seen by the source. (Hint: value of voltage should be rms value)'; '* Sample values;  3+4i, -5i, 10. If value is polar form; (mag, angle) ---> 20, 30'};
            app.ex8_calculateButton = uibutton(app.Ex8Tab, 'push');
            app.ex8_calculateButton.ButtonPushedFcn = createCallbackFcn(app, @ex8_calculateButtonPushed, true);
            app.ex8_calculateButton.Text = 'CALCULATE';
            app.ex8_clearButton = uibutton(app.Ex8Tab, 'push');
            app.ex8_clearButton.ButtonPushedFcn = createCallbackFcn(app, @ex8_clearButtonPushed, true);
            app.ex8_clearButton.Text = 'CLEAR';
            app.TheapparentpowerLabel_4 = uilabel(app.Ex8Tab);
            app.TheapparentpowerLabel_4.Text = 'The average power:';
            app.ap8 = uieditfield(app.Ex8Tab, 'text');
            app.ap8.Editable = 'off';
            app.ap8.Placeholder = 'P';
            app.VrmsLabel_2 = uilabel(app.Ex8Tab);
            app.VrmsLabel_2.Text = 'Vrms:';
            app.v8 = uieditfield(app.Ex8Tab, 'text');
            app.v8.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.v8.ValueChangingFcn = createCallbackFcn(app, @polarForm, true);
            app.v8.Placeholder = 'V';
            app.r8a = uieditfield(app.Ex8Tab, 'text');
            app.r8a.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.r8a.ValueChangingFcn = createCallbackFcn(app, @polarForm, true);
            app.r8a.Placeholder = 'Ω';
            app.i8 = uieditfield(app.Ex8Tab, 'text');
            app.i8.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.i8.ValueChangingFcn = createCallbackFcn(app, @polarForm, true);
            app.i8.Placeholder = 'Ω';
            app.r8b = uieditfield(app.Ex8Tab, 'text');
            app.r8b.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.r8b.ValueChangingFcn = createCallbackFcn(app, @polarForm, true);
            app.r8b.Placeholder = 'Ω';
            app.c8 = uieditfield(app.Ex8Tab, 'text');
            app.c8.ValueChangedFcn = createCallbackFcn(app, @polarForm, true);
            app.c8.ValueChangingFcn = createCallbackFcn(app, @polarForm, true);
            app.c8.Placeholder = 'Ω';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Chapter_2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end